classdef wfsorter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MapLabel                        matlab.ui.control.Label
        AlignLabel                      matlab.ui.control.Label
        AlignMethodDropDown             matlab.ui.control.DropDown
        PairdistButton                  matlab.ui.control.Button
        NormVarCheckBox                 matlab.ui.control.CheckBox
        MethodClusterDropDown           matlab.ui.control.DropDown
        NormObsCheckBox                 matlab.ui.control.CheckBox
        NormalizeClusteringCheckBox     matlab.ui.control.CheckBox
        NormButton                      matlab.ui.control.Button
        ThreshOutlierEditField          matlab.ui.control.NumericEditField
        ThreshOutlierEditFieldLabel     matlab.ui.control.Label
        RemoveWFButtonGroup             matlab.ui.container.ButtonGroup
        RemoveWFdotButton               matlab.ui.control.Button
        RemoveWFlineButton              matlab.ui.control.Button
        RemoveWFunitButton              matlab.ui.control.Button
        SelectWFButtonGroup             matlab.ui.container.ButtonGroup
        SelectWFlineButton              matlab.ui.control.Button
        SelectWFdotButton               matlab.ui.control.Button
        ExcludeWFButtonGroup            matlab.ui.container.ButtonGroup
        ExcludeWFunitButton             matlab.ui.control.Button
        ExcludeWFlineButton             matlab.ui.control.Button
        ExcludeWFdotButton              matlab.ui.control.Button
        MethodDropDownLabel_2           matlab.ui.control.Label
        MethodOutlierDropDown           matlab.ui.control.DropDown
        SelectoutliersButton            matlab.ui.control.Button
        OutlierButtonGroup              matlab.ui.container.ButtonGroup
        OutlierdotButton                matlab.ui.control.RadioButton
        OutlierlineButton               matlab.ui.control.RadioButton
        HelpButton                      matlab.ui.control.Button
        StatisticsButton                matlab.ui.control.Button
        StatisticsButtonGroup           matlab.ui.container.ButtonGroup
        SortedButton                    matlab.ui.control.RadioButton
        AllButton                       matlab.ui.control.RadioButton
        MethodDropDownLabel             matlab.ui.control.Label
        AverageWFButton                 matlab.ui.control.Button
        UnitsLabel                      matlab.ui.control.Label
        MergeunitsButton                matlab.ui.control.Button
        unit2Spinner                    matlab.ui.control.Spinner
        unit2SpinnerLabel               matlab.ui.control.Label
        unit1Spinner                    matlab.ui.control.Spinner
        unit1SpinnerLabel               matlab.ui.control.Label
        ClusteringLabel                 matlab.ui.control.Label
        ImportclusteringButton          matlab.ui.control.Button
        ImportclusteringEditField       matlab.ui.control.EditField
        ImportclusteringEditFieldLabel  matlab.ui.control.Label
        PCSpinner                       matlab.ui.control.Spinner
        PCLabel                         matlab.ui.control.Label
        kSpinner                        matlab.ui.control.Spinner
        kSpinnerLabel                   matlab.ui.control.Label
        AutomaticclusterButton          matlab.ui.control.Button
        YDropDown                       matlab.ui.control.DropDown
        XDropDown                       matlab.ui.control.DropDown
        YCheckBox                       matlab.ui.control.CheckBox
        CheckLabel                      matlab.ui.control.Label
        CheckinsignalButton             matlab.ui.control.Button
        VisualizeISIButton              matlab.ui.control.Button
        VisualizedistButton             matlab.ui.control.Button
        SelectUnitSpinner               matlab.ui.control.Spinner
        SelectUnitSpinnerLabel          matlab.ui.control.Label
        RecalculatemapButton            matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        xSpinner                        matlab.ui.control.Spinner
        xSpinnerLabel                   matlab.ui.control.Label
        XCheckBox                       matlab.ui.control.CheckBox
        VarButton                       matlab.ui.control.RadioButton
        ySpinner                        matlab.ui.control.Spinner
        ySpinnerLabel                   matlab.ui.control.Label
        tSNESpinner                     matlab.ui.control.Spinner
        tSNEButton                      matlab.ui.control.RadioButton
        PCAButton                       matlab.ui.control.RadioButton
        ShowDropDown                    matlab.ui.control.DropDown
        ShowDropDownLabel               matlab.ui.control.Label
        RestartButton                   matlab.ui.control.Button
        EndsortingButton                matlab.ui.control.Button
        VisualizeWFButton               matlab.ui.control.Button
        RestartButtonGroup              matlab.ui.container.ButtonGroup
        ThreshEditFieldRestart          matlab.ui.control.NumericEditField
        ThreshEditFieldRestartLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        X
        thr
        L
        pr
        align
        
        wf 
        wf_org
        wfdr
        
        idx_wf 
        idx_valid
        idx_units
    end
    
    properties (Access = private)
        fig
        unitcolors
        axwf
            plotwf
        axdr
            plotdr
        axhistdr
            histdr
        figISI
        figavg
        axsignal
        figpdist
        
        run_wfthr

        sortstats
    end
    
    methods (Access = private)

        % Plot graphs of clustered waveforms
        function [] = plotWaveforms(app)

        disp('Plotting waveforms and map...')    
        axes(app.axwf)
        cla(app.axwf)
        
        idx_valid = app.idx_valid;
        idx_units = app.idx_units;
            if numel(idx_valid)>10000
                disp(['Not showing ' num2str(numel(idx_valid)-10000) ' wfs'])
%                 idx_graph = 1:10000; %plot first 3000
                idx_graph = randperm(numel(idx_valid),10000); %plot random 10000
                idx_valid = idx_valid(idx_graph);
                idx_units = idx_units(idx_graph,:);    
            end
        
        idx_notunits = sum(idx_units,2)==0;
        hold on,app.plotwf=plot(app.wf(idx_valid(idx_notunits),:)','w'); hold off
        
        if strcmp(app.ShowDropDown.Value, 'Single unit')
            iunit = app.SelectUnitSpinner.Value; 
            if intersect(iunit,idx_units)
                hold on,app.plotwf=plot(app.wf(idx_valid(idx_units==iunit),:)',app.unitcolors{iunit}); hold off
            end
        
        elseif strcmp(app.ShowDropDown.Value, 'All units')
            for iunit = unique(idx_units(idx_units>0))'
            if iunit
                hold on,app.plotwf=plot(app.wf(idx_valid(idx_units==iunit),:)',app.unitcolors{iunit}); hold off
            end
            end
        end
        
        % Scatter
        axes(app.axdr)
        cla(app.axdr)
        % all valid
%         hold on,app.plotdr=scatter(app.wfdr(idx_valid,1),...
%             app.wfdr(idx_valid,2),'.w'); hold off
        % valid but not units
        hold on,app.plotdr=scatter(app.wfdr(idx_valid(idx_notunits),1),...
            app.wfdr(idx_valid(idx_notunits),2),'.w'); hold off
        
        if strcmp(app.ShowDropDown.Value, 'Single unit')
            if intersect(iunit,idx_units)
                hold on,app.plotdr=scatter(app.wfdr(idx_valid(idx_units==iunit),1),...
                    app.wfdr(idx_valid(idx_units==iunit),2),'.',app.unitcolors{iunit}); hold off
            end
        elseif strcmp(app.ShowDropDown.Value, 'All units')
            for iunit = unique(idx_units(idx_units>0))'
            if iunit
            hold on,app.plotdr=scatter(app.wfdr(idx_valid(idx_units==iunit),1),...
                app.wfdr(idx_valid(idx_units==iunit),2),'.',app.unitcolors{iunit}); hold off
            end
            end
        end
%         pause(0)
        disp('Plotted waveforms and map')
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, X, thr, L, pr, align)
    
        app.UIFigure.Name = 'Alfred Manual Waveform Sorter';
        movegui(app.UIFigure,'onscreen')
        
        % Get or keep original inputs
            if isempty(app.X)
                app.X = X;
            end        

            if nargin<3 % obs: app+...
                thr = []; 
            end
            if nargin<4
                L = []; 
            end
            if nargin<5
                pr = []; 
            end
            if nargin<6 
                align = 'thr'; 
            end
            app.thr=thr; app.L=L; app.pr=pr; app.align=align; 
            
        % Display align method    
            app.AlignMethodDropDown.Value = app.align;
            
        % Concatenated waveforms
        % X is already concatenated array of wf 
        if ~isvector(X) % dont run wfthr
            app.run_wfthr = 0; 
            
            wf = X;
            idx_wf = 1:size(wf,1);
            
            %Second input 'thr' is index/time vector of wfs
            if ~isempty(thr) 
                if size(wf,1) == numel(thr)
                    idx_wf = thr;
                    disp('Second input ''thr'' is used as index/time vector of wfs')
                elseif size(wf,1) ~= numel(thr)
                    warning('Rows of ''X'' (waveforms) do not match number of ''thr'' index/time vector. Ignoring ''thr''')
                end
            end
            
            %Third input 'L' is clustering vector of wfs
            if ~isempty(L) 
                if size(wf,1) == numel(L)
                    app.idx_units = L;
                    disp('Third input ''L'' is used as clustering vector of wfs')
                elseif size(wf,1) ~= numel(L)
                    warning('Rows of ''X'' (waveforms) do not match number of ''L'' clustering vector. Ignoring ''L''')
                end
            end
            
        % X is signal vector
        elseif isvector(X) % run wfthr
            app.run_wfthr = 1; %run wfthr
                        
            %run wfthr
            %detecting wfs
            disp('Detecting spikes')
            [wf,idx_wf,thr] = wfthr(X,thr,L,pr,align);
            app.thr = thr;
            
            if thr==0
                disp('Duplicating wf') %for graphs
                    wf = [wf wf];
            end
        end
       
        % Create figure
            app.fig = figure;
            movegui(app.fig,'onscreen')
            
        % Create axes
            app.axwf = subplot(1,2,1);
            app.axdr = subplot(1,2,2);
        
            set(app.axwf,'color','k')
            set(app.axdr,'color','k')
            
            title(app.axwf, 'Waveforms')
            xlabel(app.axwf, 'Time (samples)')
            ylabel(app.axwf, 'Amp.')
            
            title(app.axdr, 'Sorter')
            xlabel(app.axdr, 'Dimension 1')
            ylabel(app.axdr, 'Dimension 2')
        
        % Get waveforms, index and dimensions
            app.wf = wf;
            if isempty(app.idx_wf)
                app.idx_wf = idx_wf;
            end
            app.idx_valid = [1:size(app.wf,1)]';
            if isempty(app.idx_units)
                app.idx_units = zeros(numel(app.idx_valid),1);
            end
            
            if size(app.wf,2)==2 %if only two variables
                app.wfdr = app.wf;
                app.VarButton.Value = true;
                if thr==0 %if actually only one variable
                    %make dimension 1 = Time
                    app.XCheckBox.Value = true;
                    app.XDropDown.Value = 'Time';
                    app.wfdr(:,1) = app.idx_wf(app.idx_valid);
                end
            else %many variables
                [~,app.wfdr] = pca(app.wf,'NumComponents',2);
                %make dimensions 1 e 2 = PCA
                app.PCAButton.Value = true;
                    app.xSpinner.Value = 1;
                    app.ySpinner.Value = 2;
                app.XCheckBox.Value = false;
                app.YCheckBox.Value = false;
            end

            
        % Units setup
            app.SelectUnitSpinner.Value = 1;
            app.unitcolors = {'y','g','c','m','b','r'...
                            ,'y','g','c','m','b','r'};
                        
        % Plot
            plotWaveforms(app)
            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            if exist('wfsorterhelp')
                help wfsorterhelp                
            end
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            if isvalid(app.fig)
                close(app.fig)
            end
            
            app.idx_units=[];
            
            %Chaged parameters
            % New threshold
            if app.ThreshEditFieldRestart.Value %not zero
                app.thr = app.ThreshEditFieldRestart.Value;
            else %automatic threshold
                app.thr = [];
            end
                        
            % New alignment method
            app.align = app.AlignMethodDropDown.Value;
            
            startupFcn(app,app.X,app.thr,app.L,app.pr,app.align);

        end

        % Button pushed function: VisualizeWFButton
        function VisualizeWFButtonPushed(app, event)
           
            % Select pt from map to visualize wf
            [xpts,ypts] = getpts(app.axdr);
            [~,idx_view] = min(pdist2([xpts ypts],app.wfdr(app.idx_valid,1:2)));
            
            axes(app.axwf)
            hold on,viewwf=plot(app.wf(app.idx_valid(idx_view),:)','linewidth',4,'color','y'); hold off
            pause(1)
            delete(viewwf)

        end

        % Button pushed function: ExcludeWFlineButton
        function ExcludeWFlineButtonPushed(app, event)
            roi = drawline(app.axwf,'Color','r');
            disp('Excluding wfs from lines')

            idx_int=zeros(1,numel(app.idx_valid));
            idx_noint=zeros(1,numel(app.idx_valid));
            
            for iwf = 1:numel(app.idx_valid)
                [xint,yint] = polyxpoly(1:size(app.wf,2), app.wf(app.idx_valid(iwf),:),   roi.Position(:,1),roi.Position(:,2));
                idx_int(iwf) = ~isempty(xint);
                idx_noint(iwf) = isempty(xint);
            end
            idx_int=logical(idx_int);
            idx_noint=logical(idx_noint);
            delete(roi)
            
            % Exclude idx_int
            app.idx_valid = app.idx_valid(idx_noint);
            app.idx_units = app.idx_units(idx_noint);

            %Plot results
            plotWaveforms(app)
        
        end

        % Button pushed function: ExcludeWFdotButton
        function ExcludeWFdotButtonPushed(app, event)
            roi = drawfreehand(app.axdr,'Color','r');
            disp('Excluding wfs from map')

            [idx_in] = inpolygon(app.wfdr(app.idx_valid,1),app.wfdr(app.idx_valid,2),roi.Position(:,1),roi.Position(:,2));
            delete(roi)
            
            % Exclude idx_in
            app.idx_valid = app.idx_valid(idx_in==0);
            app.idx_units = app.idx_units(idx_in==0);

            %Plot results
            plotWaveforms(app)
            
        end

        % Button pushed function: ExcludeWFunitButton
        function ExcludeWFunitButtonPushed(app, event)
            unitN = app.SelectUnitSpinner.Value;
            idx_unit = app.idx_units==unitN;

            disp(['Excluding unit ' num2str(unitN)])
            
            % Exclude idx_unit
            assignin('base','idx_unit',idx_unit)
            app.idx_valid = app.idx_valid(idx_unit==0);
            app.idx_units = app.idx_units(idx_unit==0);

            %Plot results
            plotWaveforms(app)
        end

        % Button pushed function: RecalculatemapButton
        function RecalculatemapButtonPushed(app, event)
            disp('Recalculating map...')
            if app.PCAButton.Value == true
                PCx = app.xSpinner.Value; PCy = app.ySpinner.Value;
                [~,wfdr] = pca(app.wf(app.idx_valid,:),'NumComponents',max([PCx PCy]));
                wfdr = [wfdr(:,PCx) wfdr(:,PCy)];
            end
            
            if app.VarButton.Value == true
                wfdr(:,1) = app.wf(app.idx_valid,app.xSpinner.Value);
                wfdr(:,2) = app.wf(app.idx_valid,app.ySpinner.Value);
            end
            
            if app.tSNEButton.Value == true
                NumPCAComponents = app.tSNESpinner.Value;
                disp(['t-SNE with ' num2str(NumPCAComponents) ' PCs'])
                [~,datatotsne,~,~,varexp] = pca(app.wf(app.idx_valid,:),'NumComponents',NumPCAComponents);
                disp([num2str(sum(varexp(1:NumPCAComponents))) '% of variance explained'])
                
                wfdr = tsne(datatotsne,'NumDimensions',2);
            end
            
            if app.XCheckBox.Value == true
                switch app.XDropDown.Value
                    case 'Index'
                        wfdr(:,1) = 1:size(wfdr,1);
                    case 'Time'
                        wfdr(:,1) = app.idx_wf(app.idx_valid);
                    case 'Min'
                        wfdr(:,1) = min(app.wf(app.idx_valid,:),[],2);
                    case 'Max'
                        wfdr(:,1) = max(app.wf(app.idx_valid,:),[],2);   
                end
            end
            
            if app.YCheckBox.Value == true
                switch app.YDropDown.Value
                    case 'Min'
                        wfdr(:,2) = min(app.wf(app.idx_valid,:),[],2);
                    case 'Max'
                        wfdr(:,2) = max(app.wf(app.idx_valid,:),[],2);   
                    case 'Max (Mod.)'
                        wfdr(:,2) = max(abs(app.wf(app.idx_valid,:)),[],2);   
                    case 'Energy'
                        wfdr(:,2) = sum(abs(app.wf(app.idx_valid,:)).^2,2);   
                    case 'Template'
                        iunit = app.SelectUnitSpinner.Value;
                        tmp = mean(app.wf(app.idx_valid(app.idx_units==iunit),:),1);
                        %Calculate similatiry to template (current unit average)
                        wfdr(:,2) = pdist2(tmp,app.wf(app.idx_valid,:),'euclidean');
                end
            end
                       
            app.wfdr(app.idx_valid,1:2) = wfdr(:,1:2);
            
            % Plot results
            plotWaveforms(app)
            disp('Recalculated map')

        end

        % Value changed function: ShowDropDown
        function ShowDropDownValueChanged(app, event)
            
            plotWaveforms(app)

%           %Forgot why I wrote the following            
%             unitN = app.SelectUnitSpinner.Value;      
%             if intersect(unitN,app.idx_units) %if unit exists
%                 if strcmp(app.ShowDropDown.Value, 'Single unit')
%                 plotWaveforms(app)
%                 end
%             end

        end

        % Button pushed function: SelectoutliersButton
        function SelectoutliersButtonPushed(app, event)
            %Select data to detect outliers (line or dot)
            if app.OutlierlineButton.Value == true
                datatoout = app.wf(app.idx_valid,:); 
            elseif app.OutlierdotButton.Value == true
                datatoout = app.wfdr(app.idx_valid,:); 
            end
            
            %Outlier detection based on method
            switch app.MethodOutlierDropDown.Value
                case 'median'
                    disp('Performing 3 scaled median absolute deviation outlier detection')
                    idx_out = isoutlier(datatoout,'median',1);
 
                case 'mean' 
                    disp('Performing 3 std from mean outlier detection')
                    idx_out = isoutlier(datatoout,'mean',1);

                case 'quartiles'
                    disp('Performing Tukey fences outlier detection')
                    idx_out = isoutlier(datatoout,'quartiles',1);
                    
                case 'threshold'
                    disp('Performing specified threshold outlier detection')
                    thr = app.ThreshOutlierEditField.Value;
                    if thr>0
                        idx_out = datatoout>thr;
                    elseif thr<0
                        idx_out = datatoout<thr;
                    end
                    
                case 'density'
                    disp('Performing density-based threshold outlier detection')
                    
                    N = size(datatoout,1);
                    
                    %Histogram densities
                    nbins = 100; %across 100 2D bins
                    [D,~,~,idxbinX,idxbinY] = histcounts2(datatoout(:,1),datatoout(:,2),nbins);
                    D = D/size(datatoout,1); %proportion dist.
                    Dsort = sort(D(:));
                    
                    %Density-based threshold
                    if app.ThreshOutlierEditField.Value<=0
                        Pthr = 1/100;
                    else
                        Pthr = app.ThreshOutlierEditField.Value; %ratio of knee point
                    end
                    thr = Pthr*Dsort(findchangepts(Dsort)); %weighted kneet point of distributions
                    
                    [outbinX,outbinY] = find(D<thr);
                    
                    idxbinXY = [idxbinX idxbinY];
                    outbinXY = [outbinX outbinY];
                    
                    idx_out = [];
                    iter = 1;
                    for ibin = 1:size(outbinXY,1)
                        idx_out{iter,1} = [find(sum(idxbinXY==outbinXY(ibin,:),2)==2)];
                        iter=iter+1;
                    end
                    idx_out = cell2mat(idx_out);
                    idx01 = false(N,1); idx01(idx_out) = true; %convert indices to logical
                    idx_out = idx01; clear idx01;
                    
            end
            idx_out = any(idx_out,2); %gather x and y
            disp(['Identified ' num2str(sum(idx_out)) ' outliers'])
            
            %Select outliers as current unit 
            if sum(idx_out)
            disp('Selecting outliers to current unit')
            unitN = app.SelectUnitSpinner.Value; 
            app.idx_units(idx_out,:) = unitN;
            end
            
            plotWaveforms(app)
            
        end

        % Value changed function: SelectUnitSpinner
        function SelectUnitSpinnerValueChanged(app, event)
            unitN = app.SelectUnitSpinner.Value;      
            
            if intersect(unitN,app.idx_units) %if unit exists
                if strcmp(app.ShowDropDown.Value, 'Single unit')
                plotWaveforms(app)
                end
            end
        end

        % Callback function
        function SelectUnitEditFieldValueChanged(app, event)
            unitN = app.SelectUnitSpinner.Value;      
            
            if intersect(unitN,app.idx_units)
                if strcmp(app.ShowDropDown.Value, 'Single unit')
                plotWaveforms(app)
                end
            end
        end

        % Button pushed function: SelectWFdotButton
        function SelectWFdotButtonPushed(app, event)
            unitN = app.SelectUnitSpinner.Value;      
        
            roi = drawfreehand(app.axdr,'Color',app.unitcolors{unitN});
            disp('Selecting wfs in map')
            
            [idx_in] = inpolygon(app.wfdr(app.idx_valid,1),app.wfdr(app.idx_valid,2),roi.Position(:,1),roi.Position(:,2));
            delete(roi)
            
            % Select idx_in
            app.idx_units(idx_in) = unitN;
            
            %Plot results
            plotWaveforms(app)
            
            %obs: 
            % fazer units aparecerem sempre nos gráficos 
            %       hold on plot per unit, idx_unit, unitcolors(unitN)
            %       fazer unit1 == valid but not unit
            %       ja que validação e seleção são por coordenadas
            % estabelecer N units antes? 
            %       assim fazer down/lista no gui


        end

        % Button pushed function: SelectWFlineButton
        function SelectWFlineButtonPushed(app, event)
            unitN = app.SelectUnitSpinner.Value;      
            
            roi = drawline(app.axwf,'Color',app.unitcolors{unitN});
            disp('Selecting wfs in lines')

            idx_int=zeros(1,numel(app.idx_valid));
            idx_noint=zeros(1,numel(app.idx_valid));
            for iwf = 1:numel(app.idx_valid)
                [xint,yint] = polyxpoly(1:size(app.wf,2), app.wf(app.idx_valid(iwf),:),   roi.Position(:,1),roi.Position(:,2));
                idx_int(iwf) = ~isempty(xint);
                idx_noint(iwf) = isempty(xint);
            end
            
            idx_int=logical(idx_int);
            idx_noint=logical(idx_noint);
            delete(roi)
            
            % Select idx_in
            app.idx_units(idx_int) = unitN; 

            %Plot results
            plotWaveforms(app)
 
        end

        % Button pushed function: RemoveWFdotButton
        function RemoveWFdotButtonPushed(app, event)
            unitN = app.SelectUnitSpinner.Value;      
        
            roi = drawfreehand(app.axdr,'Color','w');
            disp('Removing wfs in map')
            
            [idx_in] = inpolygon(app.wfdr(app.idx_valid,1),app.wfdr(app.idx_valid,2),roi.Position(:,1),roi.Position(:,2));
            delete(roi)
            
            % Remove idx_in
            app.idx_units(app.idx_units == unitN & idx_in) = 0; 

            %Plot results
            plotWaveforms(app)            

        end

        % Button pushed function: RemoveWFlineButton
        function RemoveWFlineButtonPushed(app, event)
            unitN = app.SelectUnitSpinner.Value;      
            
            roi = drawline(app.axwf,'Color',app.unitcolors{unitN});
            disp('Removing wfs in lines')
            
            idx_int=zeros(numel(app.idx_valid),1);
            idx_noint=zeros(numel(app.idx_valid),1);
            for iwf = 1:numel(app.idx_valid)
                [xint,yint] = polyxpoly(1:size(app.wf,2), app.wf(app.idx_valid(iwf),:),   roi.Position(:,1),roi.Position(:,2));
                idx_int(iwf) = ~isempty(xint);
                idx_noint(iwf) = isempty(xint);
            end
            idx_int=logical(idx_int);
            idx_noint=logical(idx_noint);
            delete(roi)
            
            % Remove idx_int
            app.idx_units(app.idx_units == unitN & idx_int) = 0; 

            %Plot results
            plotWaveforms(app)
 
        end

        % Button pushed function: RemoveWFunitButton
        function RemoveWFunitButtonPushed(app, event)
            unitN = app.SelectUnitSpinner.Value;      
            
            disp(['Removing unit ' num2str(unitN)])

            % Remove idx_in
            app.idx_units(app.idx_units==unitN) = 0; 

            %Plot results
            plotWaveforms(app)   
        end

        % Button pushed function: MergeunitsButton
        function MergeunitsButtonPushed(app, event)
            unitstomerge = [app.unit1Spinner.Value app.unit2Spinner.Value];

%             %Do nothing if any unit is zero or greater than current number of units
%             if any(unitstomerge==0) |... 
%                 max(unitstomerge) > max(unique(app.idx_units)) 
%                 return %do nothing
%             end
%             
%             %Sort units (useful if implement multiple units to merge)
%             unitstomerge = sort(unitstomerge);
%             disp(['Merging units ' num2str(unitstomerge)])
%             
%             for iunit = unitstomerge(2:end)
%             app.idx_units(app.idx_units==iunit) = unitstomerge(1); %merged becomes first unit
%             end
            
            %Do nothing if all units are 0 or if any unit is greater than current number of units
            if sum(unitstomerge) <= 0 | max(unitstomerge) > max(unique(app.idx_units)) 
                return %do nothing
            end
            
            %Sort units (useful if implement multiple units to merge)
            unitstomerge = sort(unitstomerge);
            disp(['Merging units ' num2str(unitstomerge)])

            if ~any(unitstomerge==0) %not merge unsorted
                for iunit = unitstomerge(2:end)
                app.idx_units(app.idx_units==iunit) = unitstomerge(1); %merged becomes first unit
                end                
            else %merge unsorted to units
                for iunit = unitstomerge(1:end)
                    if iunit == unitstomerge(2) %skip
                        continue
                    end
                app.idx_units(app.idx_units==iunit) = unitstomerge(2); %merged becomes second unit
                end
            end
            
            plotWaveforms(app)
            
        end

        % Button pushed function: AverageWFButton
        function AverageWFButtonPushed(app, event)
            unitstoplot = unique(app.idx_units(app.idx_units>=0)); %only units with wf
            
            if isempty(unitstoplot)
                disp('No units to plot yet')      
                return
            end
            
            Nunits = numel(unitstoplot);
            
            Nsbprow = round(sqrt(Nunits+1));
            Nsbpcol = ceil(sqrt(Nunits+1));
            
            % new figure if not existant or use previous
            if isempty(app.figavg) | ~isvalid(app.figavg)
                app.figavg = figure;
            else
                close(app.figavg)   
                app.figavg = figure;
            end
            movegui(app.figavg,'onscreen')

            % Average waveform and deviation
            sbp=1; 
            for iunit=unitstoplot'
                wf = app.wf(app.idx_valid(app.idx_units==iunit),:);
                % Avearge
                avg_wf = mean(wf,1);
                    % Deviations
                    std_wf = std(wf,[],1); %standard deviation
                    sem_wf = std_wf/sqrt(size(wf,1)); %standard error              
                    ts = tinv([0.025  0.975],size(wf,1)-1); %alpha 0.05    
                    ic_wf = ts(2)'*sem_wf; %confidence interval
                
                % Subplot each unit average
                if iunit %>0 sorted
                    unitcolor = app.unitcolors{iunit};
                else %0 unsorted
                    unitcolor = 'w';
                end
                
                subplot(Nsbprow,Nsbpcol,sbp);
                hold on,plot(avg_wf,...
                    'color',unitcolor,...
                    'linewidth',2); hold off
                hold on,plot([avg_wf+std_wf ; avg_wf-std_wf]',...
                    'color',unitcolor,...
                    "LineStyle","--",...
                    'linewidth',2); hold off   
                
                axavg(sbp)=gca; 
                sbp=sbp+1;

                if iunit
                    title(['Unit ' num2str(iunit)])
                else
                    title('Unsorted')
                end
                xlabel('Time (samples)')
                ylabel('Amp.')
                
                %Subplot all units
                subplot(Nsbprow,Nsbpcol,Nunits+1);
                hold on,plot(avg_wf,...
                    'color',unitcolor,...
                    'linewidth',2); hold off
                hold on,plot([avg_wf+std_wf ; avg_wf-std_wf]',...
                    'color',unitcolor,...
                    "LineStyle","--",...
                    'linewidth',2); hold off 
                axavg(Nunits+1)=gca;
                title('All')
                xlabel('Time (samples)')
                ylabel('Amp.')
            end
            set(axavg,'color','k')
            linkaxes(axavg,'y')
        end

        % Button pushed function: VisualizedistButton
        function VisualizedistButtonPushed(app, event)
        
            idx_valid = app.idx_valid;
            idx_units = app.idx_units;
            idx_notunits = idx_units==0;
            
            unitstoplot = unique(idx_units(idx_units>0));

            % new figure if not existant or use previous
            if isempty(app.axhistdr) | ~isvalid(app.axhistdr)
                figure;
                movegui(gcf,'onscreen')
                app.axhistdr=gca;
            else
                axes(app.axhistdr)
                cla(app.axhistdr)                
            end
            
            % Histogram
%             axes(app.axhistdr)
%             cla(app.axhistdr)
            hold on,histdr(1)=histogram2(app.wfdr(idx_valid(idx_notunits),1),...
                app.wfdr(idx_valid(idx_notunits),2),...
                'facecolor','w','edgecolor','none',...
                'binwidth',[.01 .01]); hold off
            
            if strcmp(app.ShowDropDown.Value, 'Single unit')
                iunit = app.SelectUnitSpinner.Value;
                    hold on,histdr=histogram2(app.wfdr(idx_valid(idx_units==iunit),1),...
                        app.wfdr(idx_valid(idx_units==iunit),2),...
                        'facecolor',app.unitcolors{iunit},'edgecolor','none',...
                        'BinWidth',[.01 .01]); hold off
            elseif strcmp(app.ShowDropDown.Value, 'All units')
                for iunit = unitstoplot'
                hold on,histdr=histogram2(app.wfdr(idx_valid(idx_units==iunit),1),...
                    app.wfdr(idx_valid(idx_units==iunit),2),...
                    'facecolor',app.unitcolors{iunit},'edgecolor','none',...
                    'binwidth',[.01 .01]); hold off
                end
            end
            
            title(app.axhistdr, 'Distribution histogram')
            xlabel(app.axhistdr,'Dimension 1')
            ylabel(app.axhistdr,'Dimension 2')
            zlabel(app.axhistdr,'Counts')
            
            set(app.axhistdr,'color','k',...
                'xlim',app.axdr.XLim,'ylim',app.axdr.YLim)
            view(-45,45)

        end

        % Button pushed function: VisualizeISIButton
        function VisualizeISIButtonPushed(app, event)
            if ~isvector(app.X) 
%             if app.run_wfthr==0  %stop here if original not vector
                warning('Original data is not vector')
%                 return
            end
            
            idx_wf = app.idx_wf;
            idx_valid = app.idx_valid;
            idx_units = app.idx_units;
            
            unitstoplot = unique(idx_units(idx_units>=0));
            if isempty(unitstoplot)
                disp('No units to plot yet')      
                return
            end
            
            Nunits = numel(unitstoplot);
            
            Nsbprow = round(sqrt(Nunits));
            Nsbpcol = ceil(sqrt(Nunits));
            
            % new figure if not existent or use previous
            if isempty(app.figISI) | ~isvalid(app.figISI)
                app.figISI = figure;
            else
                close(app.figISI)   
                app.figISI = figure;
            end
            movegui(app.figISI,'onscreen')

            % Subplot each unit ISI histogram
            sbp=1;
            for iunit=unitstoplot'
                unitISI = diff(idx_wf(idx_valid(idx_units==iunit)));
                
                if iunit %>0 sorted
                    unitcolor = app.unitcolors{iunit};
                else %0 unsorted
                    unitcolor = 'w';
                end 

                subplot(Nsbprow,Nsbpcol,sbp)
                hold on,histISI=histogram(unitISI,0:1:ceil(.8*max(unitISI)),... %half of max
                    'facecolor',unitcolor,...
                    'edgecolor','none',...
                    'facealpha',1); hold off
                
                set(gca,'color','k')
                sbp=sbp+1;
                    %x-axis zero to 95%
                    xlim([0 histISI.BinEdges(find((cumsum(histISI.Values)/sum(histISI.Values))>.80,1))]) %plot  80%
%                     xlim([0 max(unitISI)/4])
                if iunit
                    title(['Unit ' num2str(iunit)])
                else
                    title('Unsorted')
                end
                xlabel('ISI')
                ylabel('Counts')
            end

        end

        % Button pushed function: CheckinsignalButton
        function CheckinsignalButtonPushed(app, event)
            if ~isvector(app.X) 
%             if app.run_wfthr==0  %stop here if original not vector
                disp('Original data is not vector. Use X = Index/Time to visualize through time')
                return
            end
            
            idx_wf = app.idx_wf;
            idx_valid = app.idx_valid;
            idx_units = app.idx_units;
            idx_notunits = idx_units==0;
            
            unitstoplot = unique(idx_units(idx_units>0)); %plot sorted
            if isempty(unitstoplot)
                disp('No units to plot yet')      
            end
           
            % new figure if not existant or use previous
            if isempty(app.axsignal) | ~isvalid(app.axsignal)
                figure;
                movegui(gcf,'onscreen')
                app.axsignal = gca;
            else
                axes(app.axsignal)
                cla(app.axsignal)     
            end

            % Subplot each unit ISI histogram
            hold on,plotsignal=plot(app.X,'w');
            set(gca,'color','k')
            title('Check sorted units in signal')
            xlabel('Time (samples)')
            ylabel('Amp.')
            
            for iunit=unitstoplot'
                idx_sorted = idx_wf(idx_valid(idx_units==iunit));
                hold on,scatter(idx_sorted,app.X(idx_sorted),...
                    's','filled','markerfacecolor',app.unitcolors{iunit})
            end
        end

        % Button pushed function: ImportclusteringButton
        function ImportclusteringButtonPushed(app, event)
            importedclust = app.ImportclusteringEditField.Value;
            
            % Did not fill text box
            if isempty(importedclust)
                disp('Type variable name of the clustering index in text box')
                return
            end
            
            % Get workspace variable
            try
                imported_idx_units = evalin('base',importedclust);
            catch % Variable does not exist
                disp(['Variable "' importedclust '" does not exist on workspace'])
                return
            end
            disp(['Importing clustering data: ' importedclust])
            
            % Imported variable does not match 
            if numel(app.idx_valid)~=size(imported_idx_units,1)
                error('Imported clustering must have the same number of rows of X')
            end
            
            app.idx_units = imported_idx_units;

            plotWaveforms(app)  
            
        end

        % Button pushed function: AutomaticclusterButton
        function AutomaticclusterButtonPushed(app, event)
            
            % Number of clusters (units)            
            k = app.kSpinner.Value;
            
            % Data to cluster
            if size(app.wf,2)==2 %only two vars
                app.PCSpinner.Value = 0;
            end
    
            if app.PCSpinner.Value %use PCA
                [~,datatoclust,~,~,varexp] = pca(app.wf(app.idx_valid,:),'NumComponents',app.PCSpinner.Value);
                disp([num2str(sum(varexp(1:app.PCSpinner.Value))) '% of variance explained'])
            else %use current wfdr
                datatoclust = app.wfdr(app.idx_valid,:);
            end
            
            % Normalize
            if app.NormalizeClusteringCheckBox.Value == true
                datatoclust = normalize(datatoclust,1,'zscore');
            end
            
            % Method of clustering
            switch app.MethodClusterDropDown.Value
                case 'GMM'
                    % Fit Gaussian Mixture Model
                    disp('Performing automatic Gaussian Mixture Model clustering')
                    GMModel = fitgmdist(datatoclust,k,'replicates',10,...
                        'CovarianceType','full','SharedCovariance',true,...
                        'ProbabilityTolerance',1e-8,'Regularizationvalue',0,...
                        'options',statset('maxiter',1000,'display','off'));
        
                    %Get posterior probabilities
                    Pthr = 0.95;
                    Pgmm = posterior(GMModel,datatoclust);
                    
                    idx_Pthr = any(Pgmm>Pthr,2); %above pos. threshold=1, below=0
        
                    %Clustering
                    idx_cluster = cluster(GMModel,datatoclust);
                    idx_cluster(idx_Pthr==0) = 0; %lower than pos. threshold is zero

                case 'k-means'
                    disp('Performing automatic k-means clustering')
                    idx_cluster = kmeans(datatoclust,k,'replicates',10,'maxiter',1000,'display','final');

                case 'linkage'
                    disp('Performing automatic hierarchical clustering')
                    
                    if size(datatoclust,1)>3000
                        ok = questdlg('Too many observations for HC. Wish to continue?','Warning');                         
                        if ~strcmp(ok,'Yes') 
                            return
                        end
                    end

                    Z = linkage(datatoclust,'ward');
%                         figure,h=dendrogram(Z,'orientation','left')
%                         set(h,'color','k')
%                         title('Dendrogram')
%                         xlabel('Dissimilarity')
                    idx_cluster = cluster(Z,'maxclust',k);
                    
                case 'dbscan'
                    disp('Performing automatic density-based clustering')
                    
                    if size(datatoclust,1)>3000
                        ok = questdlg('Too many observations for dbscan. Wish to continue?','Warning');                         
                        if ~strcmp(ok,'Yes') 
                            return
                        end
                    end    
                                      
                    minpts = round(0.05*size(datatoclust,1)); % 0.5% of data points
                    
                    D = pdist2(datatoclust,datatoclust,'euclidean','smallest',minpts);
                    D = sort(D(end,:)); 
                    kneept = findchangepts(D); %approximate knee of curve
                        figkneept=figure; 
                        plot(D); 
                        title('k-distance graph')
                        ylabel([num2str(minpts) 'th nearest distances'])
                        xlabel(['Points sorted with ' num2str(minpts) 'th nearest distances'])
                        hold on,plot([kneept kneept],[min(D) max(D)],'--k') 
                        text(length(D)/3,max(D)/2,{'Get the point for epsilon and press Enter';'Recommended: knee point (Enter/close fig.)'})
                        try %if closed uses standard kneept
                            [getkneept] = getpts; 
                            if getkneept %if empty (pressed Enter)
                                kneept=round(getkneept);
                            end
                            close(figkneept); 
                        end

                    epsilon = 1*D(kneept);
                    
                    idx_cluster = dbscan(datatoclust,epsilon,minpts);
                    
                case 'wave_clus'
                    if exist('wave_clus')
                        disp('Performing wave_clus automatic clustering')
                        
                        sr = 1;
                        index = app.idx_wf(app.idx_valid);
                        spikes = app.wf(app.idx_valid,:);
                        
                        %create folder and cd
                        cd0 = cd; 
                        cd_aux = [cd '\wfsorterwc'];
                        if ~exist(cd_aux)
                            mkdir(cd_aux)
                        end
                        cd(cd_aux)
                        
                        save('concatfiletosort.mat','spikes','index','sr')
                        Do_clustering('concatfiletosort.mat'); %wave_clus function  
                        
                        load('times_concatfiletosort.mat','cluster_class'); 
                        cd(cd0)
                        
                        idx_cluster = cluster_class(:,1);
                    else
                        disp('wave_clus is not installed')
                    end
                    
                case 'MClust'
                    disp('Under development')
                    return
                    
                case 'Kilosort'
                    disp('Under development')
                    return
            end
            
            
            %Getting idx_units
            app.idx_units = zeros(numel(app.idx_valid),1);
            for iunit = 1:k
                app.idx_units(idx_cluster==iunit,1) = iunit;
            end

            plotWaveforms(app)
            
        end

        % Value changed function: MethodClusterDropDown
        function MethodClusterDropDownValueChanged(app, event)
            if strcmp(app.MethodClusterDropDown.Value,'dbscan') |...
               strcmp(app.MethodClusterDropDown.Value,'wave_clus')
                app.kSpinnerLabel.Enable = 'off';
                app.kSpinner.Enable = 'off';
                app.PCLabel.Enable = 'off';
                app.PCSpinner.Enable = 'off';
            else
                app.kSpinnerLabel.Enable = 'on';
                app.kSpinner.Enable = 'on';
                app.PCLabel.Enable = 'on';
                app.PCSpinner.Enable = 'on';                
            end
        end

        % Button pushed function: StatisticsButton
        function StatisticsButtonPushed(app, event)
            % F MANOVA
            % p-value
            % J3
            % Pseudo-F
            % Davies-Bouldin Index
            % Dunn Index
            % Isolation Mahalanobis?

            %Don't run if no units
            if isempty(unique(app.idx_units(app.idx_units>0)))
                disp('No units to perform statistics yet')      
                return
            end
    
            %Data to perform statistics
            datatostats = app.wfdr(app.idx_valid,:);
            
            %Get all measures
            if exist('wfsorterstatsfcn')
                if app.SortedButton.Value
                    onlysorted = 1; %Ignores unsorted for statistics
                else
                    onlysorted = 0; %Includes unsorted for statistics
                end
                
                [Fmanova,pvalue,J3,PseudoF,DB,Dunn] = wfsorterstatsfcn(datatostats,app.idx_units,onlysorted);
 
                sortstats.Fmanova = Fmanova;
                sortstats.pvalue = pvalue;
                sortstats.J3 = J3;
                sortstats.PseudoF = PseudoF;
                sortstats.DBindex = DB;
                sortstats.Dunn = Dunn;
                sortstats
                
                app.sortstats = sortstats;
                
            else
                disp('Must have sortstatsfcn.m to get sorting statistics')
            end


        end

        % Button pushed function: EndsortingButton
        function EndsortingButtonPushed(app, event)

        idx_valid = app.idx_valid;
        idx_invalid = setxor(1:numel(app.idx_wf), idx_valid);
        idx_units = app.idx_units;
        
        %Set units ID in order
%         units = unique(idx_units);
%         for iunit = 1:numel(units)
%             idx_units(idx_units==units(iunit)) = iunit;
%         end
        
        if app.run_wfthr
%             wf = app.wf;
%             idx_wf = app.idx_wf; 
            
            wfresults.thr = app.thr;
            wfresults.wf = app.wf;
            wfresults.idx_wf = app.idx_wf;
            
            assignin('base','wfresults',wfresults);
            
%             X = app.X; assignin('base','X',X);
            
        end
        
        sortresults.idx_valid = idx_valid;
        sortresults.idx_units = idx_units;
        sortresults.idx_invalid = idx_invalid;
        
        %Run statistics
        StatisticsButtonPushed(app, 1);
        sortresults.sortstats = app.sortstats;        
        
        %Assign
        assignin('caller','sortresults',sortresults);
        assignin('base','sortresults',sortresults);
        
        disp('Sorting ended')
        disp('Check variable sortresults')
        
        if app.run_wfthr
            disp('Check variable wfresults')
        end
        
        close(gcbf)

%         Para parar rotina até concluir sorting usar (exemplo):
%         wfapp = manualwfsorter(X); uiwait(wfapp.UIFigure);
            
        end

        % Value changed function: MethodOutlierDropDown
        function MethodOutlierDropDownValueChanged(app, event)
            if strcmp(app.MethodOutlierDropDown.Value,'threshold') | ...
               strcmp(app.MethodOutlierDropDown.Value,'density')
                app.ThreshOutlierEditFieldLabel.Enable = 'on';
                app.ThreshOutlierEditField.Enable = 'on';
            else
                app.ThreshOutlierEditFieldLabel.Enable = 'off';
                app.ThreshOutlierEditField.Enable = 'off';
            end
            
            if strcmp(app.MethodOutlierDropDown.Value,'density')
                warning('Density-based outlier detection can only be performed on dots' )
                app.OutlierdotButton.Value = true;
                app.ThreshOutlierEditField.Value = 0.001;
            end
            
        end

        % Value changed function: XCheckBox
        function XCheckBoxValueChanged(app, event)
            if app.XCheckBox.Value == true
                app.xSpinnerLabel.Enable = 'off';
                app.xSpinner.Enable = 'off';
            elseif app.XCheckBox.Value == false
                app.xSpinnerLabel.Enable = 'on';
                app.xSpinner.Enable = 'on';
            end
        end

        % Value changed function: YCheckBox
        function YCheckBoxValueChanged(app, event)
            if app.YCheckBox.Value == true
                app.ySpinnerLabel.Enable = 'off';
                app.ySpinner.Enable = 'off';
            elseif app.YCheckBox.Value == false
                app.ySpinnerLabel.Enable = 'on';
                app.ySpinner.Enable = 'on';
            end            
        end

        % Button pushed function: NormButton
        function NormButtonPushed(app, event)
            %keep original in first normalization
            if isempty(app.wf_org) 
               app.wf_org = app.wf;
            end
            
            %Undo normalization
            if ~isempty(app.wf_org) &...
                    app.NormObsCheckBox.Value == false &...
                    app.NormVarCheckBox.Value == false
                app.wf = app.wf_org;
                    
            end
            
            %Normalize per observation
            if app.NormObsCheckBox.Value == true
                app.wf = normalize(app.wf,2,'zscore');
            end
               
            %Normalize per variable
            if app.NormVarCheckBox.Value == true
                app.wf = normalize(app.wf,1,'zscore');
            end
            
            %Recalculate map and plot
            RecalculatemapButtonPushed(app,event)
        end

        % Button pushed function: PairdistButton
        function PairdistButtonPushed(app, event)
            
            if app.PCSpinner.Value %use PCA
                [~,datatoclust,~,~,varexp] = pca(app.wf(app.idx_valid,:),'NumComponents',app.PCSpinner.Value);
                disp([num2str(sum(varexp(1:app.PCSpinner.Value))) '% of variance explained'])
            else %use current wfdr
                datatoclust = app.wfdr(app.idx_valid,:);
            end     
            
            % Normalize
            if app.NormalizeClusteringCheckBox.Value == true
                datatoclust = normalize(datatoclust,1,'zscore');
            end
            
            %Histogram
            disp('Plotting distributions of pairwise distances between observations...')
            
            N = size(datatoclust,1);
            n = 30000;
            if n>N
                n = N;
            end
            idx = randperm(N,n);
            
            D = pdist(datatoclust(idx,:));
            [hy,hx] = histcounts(D);
            hx = hx(1:end-1)+mode(diff(hx));
            
            % new figure if not existent or use previous
            if isempty(app.figpdist) | ~isvalid(app.figpdist)
                app.figpdist = figure;
            else
                close(app.figpdist)   
                app.figpdist = figure;
            end
            movegui(app.figpdist,'onscreen')
            
            bar(hx,hy,1,'facecolor','w');
            set(gca,'color','k')
            
            title('Pairwise distances between pairs of observations')
            xlabel('Distance')
            ylabel('Counts')
            
            disp('Plotted distributions')
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RestartButtonGroup = uibuttongroup(app.UIFigure);
            app.RestartButtonGroup.Tooltip = {''};
            app.RestartButtonGroup.Title = 'Restart';
            app.ThreshEditFieldRestartLabel = uilabel(app.RestartButtonGroup);
            app.ThreshEditFieldRestartLabel.Tooltip = {'New threshold for restarted sorting'; ''; 'set Threshold = 0 to use automatic threshold'};
            app.ThreshEditFieldRestartLabel.Text = 'Thresh.';
            app.ThreshEditFieldRestart = uieditfield(app.RestartButtonGroup, 'numeric');
            app.ThreshEditFieldRestart.Tooltip = {'New threshold for restarted sorting'; ''; 'set Threshold = 0 to use automatic threshold'};
            app.VisualizeWFButton = uibutton(app.UIFigure, 'push');
            app.VisualizeWFButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeWFButtonPushed, true);
            app.VisualizeWFButton.Tooltip = {'Click on dot in the map and press Enter to visualize waveform'};
            app.VisualizeWFButton.Text = 'Visualize WF';
            app.EndsortingButton = uibutton(app.UIFigure, 'push');
            app.EndsortingButton.ButtonPushedFcn = createCallbackFcn(app, @EndsortingButtonPushed, true);
            app.EndsortingButton.Tooltip = {'Returns the variable ''sortresults'' in the workspace containing the indices of valid (idx_valid) waveforms and respective units (idx_units)'; ''; 'If performed spike detection, it also returns ''wfresults'' containing the waveforms (wf), respective indices (idx_wf), and the threshold used (thr)'};
            app.EndsortingButton.Text = 'End sorting';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Tooltip = {''};
            app.RestartButton.Text = 'Restart';
            app.ShowDropDownLabel = uilabel(app.UIFigure);
            app.ShowDropDownLabel.Text = 'Show';
            app.ShowDropDown = uidropdown(app.UIFigure);
            app.ShowDropDown.Items = {'Single unit', 'All units', 'None'};
            app.ShowDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowDropDownValueChanged, true);
            app.ShowDropDown.Value = 'All units';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.PCAButton = uiradiobutton(app.ButtonGroup);
            app.PCAButton.Text = 'PCA';
            app.tSNEButton = uiradiobutton(app.ButtonGroup);
            app.tSNEButton.Text = 't-SNE';
            app.tSNESpinner = uispinner(app.ButtonGroup);
            app.tSNESpinner.Tooltip = {'Number of PCs for t-SNE calculation'};
            app.tSNESpinner.Value = 3;
            app.ySpinnerLabel = uilabel(app.ButtonGroup);
            app.ySpinnerLabel.Text = 'y';
            app.ySpinner = uispinner(app.ButtonGroup);
            app.ySpinner.Value = 2;
            app.VarButton = uiradiobutton(app.ButtonGroup);
            app.VarButton.Tooltip = {'Use specified variable numbers to display in the map'};
            app.VarButton.Text = 'Var #';
            app.VarButton.Value = true;
            app.XCheckBox = uicheckbox(app.ButtonGroup);
            app.XCheckBox.ValueChangedFcn = createCallbackFcn(app, @XCheckBoxValueChanged, true);
            app.XCheckBox.Text = 'X';
            app.xSpinnerLabel = uilabel(app.ButtonGroup);
            app.xSpinnerLabel.Text = 'x';
            app.xSpinner = uispinner(app.ButtonGroup);
            app.xSpinner.Value = 1;
            app.RecalculatemapButton = uibutton(app.UIFigure, 'push');
            app.RecalculatemapButton.ButtonPushedFcn = createCallbackFcn(app, @RecalculatemapButtonPushed, true);
            app.RecalculatemapButton.Text = 'Recalculate map';
            app.SelectUnitSpinnerLabel = uilabel(app.UIFigure);
            app.SelectUnitSpinnerLabel.Text = 'Select Unit';
            app.SelectUnitSpinner = uispinner(app.UIFigure);
            app.SelectUnitSpinner.ValueChangedFcn = createCallbackFcn(app, @SelectUnitSpinnerValueChanged, true);
            app.SelectUnitSpinner.Value = 1;
            app.VisualizedistButton = uibutton(app.UIFigure, 'push');
            app.VisualizedistButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizedistButtonPushed, true);
            app.VisualizedistButton.Tooltip = {'Plot distribution of current map data.'; ''; 'Tip: use ''Time'' as X for approximate firing rate'};
            app.VisualizedistButton.Text = 'Visualize dist.';
            app.VisualizeISIButton = uibutton(app.UIFigure, 'push');
            app.VisualizeISIButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeISIButtonPushed, true);
            app.VisualizeISIButton.Tooltip = {'Plot distribution of interspike interval (or inter indices/timestamps interval)'};
            app.VisualizeISIButton.Text = 'Visualize ISI';
            app.CheckinsignalButton = uibutton(app.UIFigure, 'push');
            app.CheckinsignalButton.ButtonPushedFcn = createCallbackFcn(app, @CheckinsignalButtonPushed, true);
            app.CheckinsignalButton.Tooltip = {'Check markers for sorted units in original signal.'; ''; 'Won''t plot if X is array or if restarted sorting'};
            app.CheckinsignalButton.Text = 'Check in signal';
            app.CheckLabel = uilabel(app.UIFigure);
            app.CheckLabel.Tooltip = {'Average waveforms'};
            app.CheckLabel.Text = 'Check';
            app.YCheckBox = uicheckbox(app.UIFigure);
            app.YCheckBox.ValueChangedFcn = createCallbackFcn(app, @YCheckBoxValueChanged, true);
            app.YCheckBox.Text = 'Y';
            app.XDropDown = uidropdown(app.UIFigure);
            app.XDropDown.Items = {'Time', 'Index', 'Min', 'Max'};
            app.XDropDown.Tooltip = {'Time = Timestamp of each obs'; ''; 'Index = Indices of each obs'; ''; 'Max = Maximum value '; ''; 'Min = Minimum value'; ''; 'Max (Mod.) = Maximum of modules of values'; ''; 'Energy = sum of squares'; ''; 'Template = dissimilarity (Euclid. dist.) to average of current unit'};
            app.XDropDown.Value = 'Time';
            app.YDropDown = uidropdown(app.UIFigure);
            app.YDropDown.Items = {'Max', 'Min', 'Max (Mod.)', 'Template', 'Energy'};
            app.YDropDown.Tooltip = {'Time = Timestamp of each obs'; ''; 'Index = Indices of each obs'; ''; 'Max = Maximum value '; ''; 'Min = Minimum value'; ''; 'Max (Mod.) = Maximum of modules of values'; ''; 'Energy = sum of squares'; ''; 'Template = dissimilarity (Euclid. dist.) to average of current unit'};
            app.YDropDown.Value = 'Max';
            app.AutomaticclusterButton = uibutton(app.UIFigure, 'push');
            app.AutomaticclusterButton.ButtonPushedFcn = createCallbackFcn(app, @AutomaticclusterButtonPushed, true);
            app.AutomaticclusterButton.Tooltip = {'Automatic clustering'};
            app.AutomaticclusterButton.Text = 'Automatic cluster';
            app.kSpinnerLabel = uilabel(app.UIFigure);
            app.kSpinnerLabel.Text = 'k';
            app.kSpinner = uispinner(app.UIFigure);
            app.kSpinner.Tooltip = {'Number of clusters'};
            app.kSpinner.Value = 2;
            app.PCLabel = uilabel(app.UIFigure);
            app.PCLabel.Text = 'PC';
            app.PCSpinner = uispinner(app.UIFigure);
            app.PCSpinner.Tooltip = {'Number of PCs for clustering. Set PC = 0 to use current map.'};
            app.ImportclusteringEditFieldLabel = uilabel(app.UIFigure);
            app.ImportclusteringEditFieldLabel.Tooltip = {'Import clustering from workspace variable. Type the name in the field and click on the button'};
            app.ImportclusteringEditFieldLabel.Text = 'Import clustering';
            app.ImportclusteringEditField = uieditfield(app.UIFigure, 'text');
            app.ImportclusteringEditField.Tooltip = {'Import clustering from workspace variable. Type the name in the field and click on the button'};
            app.ImportclusteringEditField.Value = 'type var name';
            app.ImportclusteringButton = uibutton(app.UIFigure, 'push');
            app.ImportclusteringButton.ButtonPushedFcn = createCallbackFcn(app, @ImportclusteringButtonPushed, true);
            app.ImportclusteringButton.Tooltip = {'Import clustering from workspace variable. Type the name in the field and click on the button'};
            app.ImportclusteringButton.Text = 'Import clustering';
            app.ClusteringLabel = uilabel(app.UIFigure);
            app.ClusteringLabel.Text = 'Clustering';
            app.unit1SpinnerLabel = uilabel(app.UIFigure);
            app.unit1SpinnerLabel.Tooltip = {'Merge the two units inserted below'};
            app.unit1SpinnerLabel.Text = 'unit1';
            app.unit1Spinner = uispinner(app.UIFigure);
            app.unit1Spinner.Tooltip = {'Merge the two units inserted below'};
            app.unit2SpinnerLabel = uilabel(app.UIFigure);
            app.unit2SpinnerLabel.Tooltip = {'Merge the two units inserted below'};
            app.unit2SpinnerLabel.Text = 'unit2';
            app.unit2Spinner = uispinner(app.UIFigure);
            app.unit2Spinner.Tooltip = {'Merge the two units inserted below'};
            app.MergeunitsButton = uibutton(app.UIFigure, 'push');
            app.MergeunitsButton.ButtonPushedFcn = createCallbackFcn(app, @MergeunitsButtonPushed, true);
            app.MergeunitsButton.Tooltip = {'Merge the two units inserted below'};
            app.MergeunitsButton.Text = 'Merge units';
            app.UnitsLabel = uilabel(app.UIFigure);
            app.UnitsLabel.Tooltip = {'Merge the two units inserted below'};
            app.UnitsLabel.Text = 'Units';
            app.AverageWFButton = uibutton(app.UIFigure, 'push');
            app.AverageWFButton.ButtonPushedFcn = createCallbackFcn(app, @AverageWFButtonPushed, true);
            app.AverageWFButton.Tooltip = {'Plot average waveforms and error'};
            app.AverageWFButton.Text = 'Average WF';
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.StatisticsButtonGroup = uibuttongroup(app.UIFigure);
            app.StatisticsButtonGroup.Title = 'Statistics';
            app.AllButton = uiradiobutton(app.StatisticsButtonGroup);
            app.AllButton.Text = 'All';
            app.AllButton.Value = true;
            app.SortedButton = uiradiobutton(app.StatisticsButtonGroup);
            app.SortedButton.Text = 'Sorted';
            app.StatisticsButton = uibutton(app.UIFigure, 'push');
            app.StatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @StatisticsButtonPushed, true);
            app.StatisticsButton.Tooltip = {'Returns statistical measures of sorting in the variable ''sortstats'''};
            app.StatisticsButton.Text = 'Statistics';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Tooltip = {'Displays script help in workspace'};
            app.HelpButton.Text = 'Help';
            app.OutlierButtonGroup = uibuttongroup(app.UIFigure);
            app.OutlierButtonGroup.Tooltip = {'Select outliers to current unit'};
            app.OutlierButtonGroup.Title = 'Outlier';
            app.OutlierlineButton = uiradiobutton(app.OutlierButtonGroup);
            app.OutlierlineButton.Text = 'line';
            app.OutlierlineButton.Value = true;
            app.OutlierdotButton = uiradiobutton(app.OutlierButtonGroup);
            app.OutlierdotButton.Text = 'dot';
            app.SelectoutliersButton = uibutton(app.UIFigure, 'push');
            app.SelectoutliersButton.ButtonPushedFcn = createCallbackFcn(app, @SelectoutliersButtonPushed, true);
            app.SelectoutliersButton.Tooltip = {''};
            app.SelectoutliersButton.Text = 'Select outliers';
            app.MethodOutlierDropDown = uidropdown(app.UIFigure);
            app.MethodOutlierDropDown.Items = {'median', 'mean', 'quartiles', 'threshold', 'density'};
            app.MethodOutlierDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodOutlierDropDownValueChanged, true);
            app.MethodOutlierDropDown.Tooltip = {'median = 3 scaled MAD'; ''; 'mean = 3 STD'; ''; 'quartiles = Tukey fences with k = 1.5'; ''; 'threshold = Specified threshold value. Detects above if thr>0 or lower if thr<0'; ''; 'density = density-based threshold detection. Detects dots in low density bins. Uses a proportion of the knee point (default = 0.001) that can be specified in "Thresh.".'};
            app.MethodOutlierDropDown.Value = 'median';
            app.MethodDropDownLabel_2 = uilabel(app.UIFigure);
            app.MethodDropDownLabel_2.Tooltip = {'Select outliers to current unit'};
            app.MethodDropDownLabel_2.Text = 'Method';
            app.ExcludeWFButtonGroup = uibuttongroup(app.UIFigure);
            app.ExcludeWFButtonGroup.Tooltip = {'Exclude waveforms'};
            app.ExcludeWFButtonGroup.Title = 'Exclude WF';
            app.ExcludeWFdotButton = uibutton(app.ExcludeWFButtonGroup, 'push');
            app.ExcludeWFdotButton.ButtonPushedFcn = createCallbackFcn(app, @ExcludeWFdotButtonPushed, true);
            app.ExcludeWFdotButton.Text = 'dot';
            app.ExcludeWFlineButton = uibutton(app.ExcludeWFButtonGroup, 'push');
            app.ExcludeWFlineButton.ButtonPushedFcn = createCallbackFcn(app, @ExcludeWFlineButtonPushed, true);
            app.ExcludeWFlineButton.Text = 'line';
            app.ExcludeWFunitButton = uibutton(app.ExcludeWFButtonGroup, 'push');
            app.ExcludeWFunitButton.ButtonPushedFcn = createCallbackFcn(app, @ExcludeWFunitButtonPushed, true);
            app.ExcludeWFunitButton.Text = 'unit';
            app.SelectWFButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectWFButtonGroup.Tooltip = {'Select waveforms to current unit'};
            app.SelectWFButtonGroup.Title = 'Select WF';
            app.SelectWFdotButton = uibutton(app.SelectWFButtonGroup, 'push');
            app.SelectWFdotButton.ButtonPushedFcn = createCallbackFcn(app, @SelectWFdotButtonPushed, true);
            app.SelectWFdotButton.Text = 'dot';
            app.SelectWFlineButton = uibutton(app.SelectWFButtonGroup, 'push');
            app.SelectWFlineButton.ButtonPushedFcn = createCallbackFcn(app, @SelectWFlineButtonPushed, true);
            app.SelectWFlineButton.Text = 'line';
            app.RemoveWFButtonGroup = uibuttongroup(app.UIFigure);
            app.RemoveWFButtonGroup.Tooltip = {'Remove waveforms from current unit'};
            app.RemoveWFButtonGroup.Title = 'Remove WF';
            app.RemoveWFunitButton = uibutton(app.RemoveWFButtonGroup, 'push');
            app.RemoveWFunitButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveWFunitButtonPushed, true);
            app.RemoveWFunitButton.Text = 'unit';
            app.RemoveWFlineButton = uibutton(app.RemoveWFButtonGroup, 'push');
            app.RemoveWFlineButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveWFlineButtonPushed, true);
            app.RemoveWFlineButton.Text = 'line';
            app.RemoveWFdotButton = uibutton(app.RemoveWFButtonGroup, 'push');
            app.RemoveWFdotButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveWFdotButtonPushed, true);
            app.RemoveWFdotButton.Text = 'dot';
            app.ThreshOutlierEditFieldLabel = uilabel(app.UIFigure);
            app.ThreshOutlierEditFieldLabel.Enable = 'off';
            app.ThreshOutlierEditFieldLabel.Tooltip = {'Select outliers to current unit'};
            app.ThreshOutlierEditFieldLabel.Text = 'Thresh.';
            app.ThreshOutlierEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThreshOutlierEditField.Enable = 'off';
            app.ThreshOutlierEditField.Tooltip = {'Threshold to detect outliers. Set if method is ''threshold'' or ''density'''};
            app.NormButton = uibutton(app.UIFigure, 'push');
            app.NormButton.ButtonPushedFcn = createCallbackFcn(app, @NormButtonPushed, true);
            app.NormButton.Tooltip = {'Normalize data (Z-score)'};
            app.NormButton.Text = 'Norm.';
            app.NormalizeClusteringCheckBox = uicheckbox(app.UIFigure);
            app.NormalizeClusteringCheckBox.Tooltip = {'Normalize data across observations  for clustering'};
            app.NormalizeClusteringCheckBox.Text = 'Norm.';
            app.NormObsCheckBox = uicheckbox(app.UIFigure);
            app.NormObsCheckBox.Tooltip = {'Normalize per observation'};
            app.NormObsCheckBox.Text = 'Obs.';
            app.MethodClusterDropDown = uidropdown(app.UIFigure);
            app.MethodClusterDropDown.Items = {'GMM', 'k-means', 'linkage', 'dbscan', 'wave_clus', 'MClust', 'Kilosort'};
            app.MethodClusterDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodClusterDropDownValueChanged, true);
            app.MethodClusterDropDown.Tooltip = {'GMM = Gaussian mixture models'; ''; 'k-means = k-means clustering'; ''; 'linkage = Agglomerative hierarchical tree clustering'; ''; 'dbscan = density-based clustering. Allows manual selection of epsilon based on k-graph'; ''; 'wave-clus = wave_clus clustering. Must have it installed'};
            app.MethodClusterDropDown.Value = 'GMM';
            app.NormVarCheckBox = uicheckbox(app.UIFigure);
            app.NormVarCheckBox.Tooltip = {'Normalize per variable'};
            app.NormVarCheckBox.Text = 'Var.';
            app.PairdistButton = uibutton(app.UIFigure, 'push');
            app.PairdistButton.ButtonPushedFcn = createCallbackFcn(app, @PairdistButtonPushed, true);
            app.PairdistButton.Tooltip = {'Visualize the distribution of pairwise Euclidean distance between pairs of observations. Uses PC data or current map'};
            app.PairdistButton.Text = 'Pair. dist.';
            app.AlignMethodDropDown = uidropdown(app.UIFigure);
            app.AlignMethodDropDown.Items = {'thr', 'mod', 'max', 'min'};
            app.AlignMethodDropDown.Tooltip = {'Waveform alignment method:'; ''; 'thr = by threshold'; ''; 'mod = by maximum of modulus'; ''; 'max = by maximum'; ''; 'min = by minimum'};
            app.AlignMethodDropDown.Value = 'thr';
            app.AlignLabel = uilabel(app.UIFigure);
            app.AlignLabel.Tooltip = {'Waveform alignment method:'; ''; 'thr = by threshold'; ''; 'mod = by maximum of modulus'; ''; 'max = by maximum'; ''; 'min = by minimum'};
            app.AlignLabel.Text = 'Align';
            app.MapLabel = uilabel(app.UIFigure);
            app.MapLabel.Text = 'Map';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wfsorter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end